classdef curvatureTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeptalCurvatureToolUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LoadmanualpointsButton          matlab.ui.control.Button
        SavemanualpointsButton          matlab.ui.control.Button
        PointsforcirclefittingDropDown  matlab.ui.control.DropDown
        PointsforcirclefittingLabel     matlab.ui.control.Label
        BatchanalysisButton             matlab.ui.control.Button
        CurvatureareaDropDown           matlab.ui.control.DropDown
        CurvatureareaDropDownLabel      matlab.ui.control.Label
        ReferencepointsDropDown         matlab.ui.control.DropDown
        ReferencepointsDropDownLabel    matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        TimeStepSpinner                 matlab.ui.control.Spinner
        TimeStepSpinnerLabel            matlab.ui.control.Label
        LoadContourButton               matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        ResetmanualpointsButton         matlab.ui.control.Button
        messagetxt                      matlab.ui.control.TextArea
        ContourView                     matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        CurvatureAreaView               matlab.ui.control.UIAxes
        CurvatureRatioView              matlab.ui.control.UIAxes
        CurvatureView                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        CoordContours;% contour data (4D matrix (Time, N, x, y))
        initialCall = 1;
        PixelSize; % Description
        UserSpecifiedSeptalPoints; % Description
        UserSpecifiedFreeWallPoints; % Description
        CurvatureSeptum % Septal curvature values      (N,i) N: time steps, i: mode (1:auto,2:manual)
        CurvatureFreeWall; % Freewall curvature values (N,i) N: time steps, i: mode (1:auto,2:manual)
        CurvatureRatio; % Description
        CountPointsSeptal = 0; % Description
        CountPointsFreewall = 0; % Description
        Points; % Description
        RefPoints; % Description
        ArchPoints; % Description
        ApproxCircleCenter; % Computed center of the circle
        ApproxCircleCenter_rvcurve; % center of the circle estimated from RV contour. used by area_based_minus/plus
        SubPixRes;     % Resolution described in the cvi output files.
        CurvatureArea; % 1: plus, 2: minus
        SAXimg;        % short axis slices imported from DICOM files.
        SOPInstanceUID;% used for re-arranging temporal data. 
        PixelSpacing; % Pixel Spacing on DICOM data
        NormCurvatureArea; % Normalized Curvature Area
        RefPoints_midSepWall; % re-computed RefPoints 
        weightParam;     % used to determine weights for least square fit
        registrationDone = false; % flag for registration (Manual mode)
        pointToMove;     % to save the index to move for manual correction
        ptsdirname;      % output file name (individual analysis)
    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./func');
        end

        % Button pushed function: LoadContourButton
        function LoadContourButtonPushed(app, event)
            % reset all the shared variables
            app.CoordContours               = [];
            app.PixelSize                   = [];
            app.UserSpecifiedSeptalPoints   = [];
            app.UserSpecifiedFreeWallPoints = [];
            app.PixelSize                   = [];
            app.CurvatureSeptum             = [];
            app.CurvatureFreeWall           = [];
            app.CurvatureRatio              = [];
            app.Points                      = [];
            app.RefPoints                   = [];
            app.ArchPoints                  = [];
            app.ApproxCircleCenter          = [];
            app.ApproxCircleCenter_rvcurve  = [];
            app.SubPixRes                   = [];
            app.CurvatureArea               = [];
            app.SOPInstanceUID              = [];
            app.NormCurvatureArea           = [];
            app.RefPoints_midSepWall        = []; 
            app.weightParam                 = [];    
            app.registrationDone            = false;
            app.pointToMove                 = [];
            app.ptsdirname                  = [];

            app.initialCall = 1;
            app.CountPointsSeptal           = 0;
            app.CountPointsFreewall         = 0;
 
            app.messagetxt.FontColor = 'w';
            app.messagetxt.Value = 'Auto mode.';
            app.PointsforcirclefittingDropDown.Value = 'Auto';

            read_saxDicom(app);           % data import: read DICOM first 
            contourReader_cvifile(app);   % data import: then read a cvi file
            app.TimeStepSpinner.Limits = [1,size(app.CoordContours,1)];
            axis(app.ContourView,'equal');
            axis(app.ContourView,'ij');
            xlim(app.CurvatureView,app.TimeStepSpinner.Limits);
            xlim(app.CurvatureRatioView,app.TimeStepSpinner.Limits);
            xlim(app.CurvatureAreaView,app.TimeStepSpinner.Limits);

            % Draw for Free wall
            refPointFW  = ComputeFreeWallRefPoints(app,100); % set as 80[deg]. Need validation
            for t = 1 : size(app.CoordContours,1)
                app.TimeStepSpinner.Value = t;
                % for free wall
                points{1}(1,:) = refPointFW(t,1,:);
                points{2}(1,:) = refPointFW(t,2,:);
                app.CurvatureFreeWall(t,1) = ComputeCurvature(app,points,1,2);
            end

            % Draw initial curvature plot using the reference points (Septal)
            GetSeptalCurvature_allPhases(app);            
            app.TimeStepSpinner.Value = 1;
        end

        % Value changed function: TimeStepSpinner
        function TimeStepSpinnerValueChanged(app, event)
            currentTimeStep = app.TimeStepSpinner.Value;
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Auto')
                drawContours(app,currentTimeStep);
                updateCurvaturePlot(app,app.CurvatureSeptum(:,1),app.CurvatureFreeWall(:,1));
            elseif strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                drawManualCurves(app,currentTimeStep);
                updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
            end

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportCurvatureToXlsx(app)
        end

        % Value changed function: CurvatureareaDropDown
        function CurvatureareaDropDownValueChanged(app, event)
            updateCurvaturePlot(app,app.CurvatureSeptum(:,1),app.CurvatureFreeWall(:,1));           
        end

        % Value changed function: ReferencepointsDropDown
        function ReferencepointsDropDownValueChanged(app, event)
            GetSeptalCurvature_allPhases(app);
        end

        % Button pushed function: BatchanalysisButton
        function BatchanalysisButtonPushed(app, event)
            % select the directory that includes subject data
            pathToDir = uigetdir('','Select a folder that includes patient data!');
            outXlDirName = uigetdir('','Choose the location to save output files!');
            
            % manual options
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                %existing_or_new = questdlg('Use existing manual data, or draw new curve segments?','Manual mode menu','Use existing data','Draw new','Use existing data');
                %switch existing_or_new
                %    case 'Use existing data'
                        pathToPoints = uigetdir('','Select a folder that includes manualPoints files');
                        filelist_mpts = dir(pathToPoints);
                        filelist_mpts = filelist_mpts(~ismember({filelist_mpts.name},{'.','..','.DS_Store'}));
                        filelist_mpts = filelist_mpts(~[filelist_mpts.isdir]);
                %    case 'Draw new'
                %        pathToPoints = uigetdir('','Select a folder where you want to save manual data');                        
                %end
            end
            
            % get subject directories and remove unnecessary files
            listPts = dir(pathToDir);
            listPts = listPts(~ismember({listPts.name},{'.','..','.DS_Store'}));
            listPts = listPts([listPts.isdir]);
            
            % Properties of the output xlsx file
            outXlFileName = char(['batch_septalCurvatures_n',num2str(length(listPts),'%0d'),'_',char(datetime('now','Format','yyMMdd_HHmmss')),'.xlsx']); 
            listClmn = {'Patient','CurvatureRatio_sum','CurvatureRatio_min_movavg_3','CurvatureRatio_min_movavg_5','CurvatureRatio_min_movavg_7','CurvatureRatio_min','CurvatureRatio_max','CurvatureRatio_avg',...
                                  'linebasePlus_sum',  'linebasePlus_min',  'linebasePlus_max',  'linebasePlus_avg',...   
                                  'linebaseMinus_sum', 'linebaseMinus_min', 'linebaseMinus_max', 'linebaseMinus_avg',...   
                                  'curvebasePlus_sum',  'curvebasePlus_min',  'curvebasePlus_max',  'curvebasePlus_avg',...   
                                  'curvebaseMinus_sum', 'curvebaseMinus_min', 'curvebaseMinus_max', 'curvebaseMinus_avg',...
                                  'normlinebasePlus_sum',  'normlinebasePlus_min',  'normlinebasePlus_max',  'normlinebasePlus_avg',...   
                                  'normlinebaseMinus_sum', 'normlinebaseMinus_min', 'normlinebaseMinus_max', 'normlinebaseMinus_avg',...   
                                  'normcurvebasePlus_sum',  'normcurvebasePlus_min',  'normcurvebasePlus_max',  'normcurvebasePlus_avg',...   
                                  'normcurvebaseMinus_sum', 'normcurvebaseMinus_min', 'normcurvebaseMinus_max', 'normcurvebaseMinus_avg'};

            writecell(listClmn,fullfile(outXlDirName,outXlFileName),'Sheet','Summary','Range','A1:AK1');
            h = waitbar(0,'Processing...');
            for ip = 1 : length(listPts)
                % reset all the shared variables
                app.CoordContours               = [];
                app.PixelSize                   = [];
                app.UserSpecifiedSeptalPoints   = [];
                app.UserSpecifiedFreeWallPoints = [];
                app.PixelSize                   = [];
                app.CurvatureSeptum             = [];
                app.CurvatureFreeWall           = [];
                app.CurvatureRatio              = [];
                %app.CountPointsSeptal           = [];
                %app.CountPointsFreewall         = [];
                app.Points                      = [];
                app.RefPoints                   = [];
                app.ArchPoints                  = [];
                app.ApproxCircleCenter          = [];
                app.ApproxCircleCenter_rvcurve  = [];
                app.SubPixRes                   = [];
                app.CurvatureArea               = [];
                app.SOPInstanceUID              = [];
                app.NormCurvatureArea           = [];
                app.RefPoints_midSepWall        = []; 
                app.weightParam                 = [];    
                app.registrationDone            = false;
                app.pointToMove                 = [];
                app.ptsdirname                  = [];
                app.initialCall = 1;

                app.messagetxt.FontColor = 'w';
                app.messagetxt.Value = ['Batch analysis with ',app.PointsforcirclefittingDropDown.Value,' mode .'];
                pause(0.05);

                file_cvi42 = dir(fullfile(listPts(ip).folder,listPts(ip).name,'*.cvi42wsx'));
                dirDcm = dir(fullfile(listPts(ip).folder,listPts(ip).name));
                dirDcm  = dirDcm(~ismember({dirDcm.name},{'.','..','.DS_Store'}));
                dirDcm  = dirDcm([dirDcm.isdir]);
                
                read_saxDicom(app,fullfile(dirDcm.folder,dirDcm.name));
                contourReader_cvifile(app,file_cvi42.name,file_cvi42.folder);
                app.TimeStepSpinner.Limits = [1,size(app.CoordContours,1)]; % need this for batch?
                axis(app.ContourView,'equal');                              % need this for batch?
                axis(app.ContourView,'ij');                                 % need this for batch?
                xlim(app.CurvatureView,app.TimeStepSpinner.Limits);
                xlim(app.CurvatureRatioView,app.TimeStepSpinner.Limits);
                xlim(app.CurvatureAreaView,app.TimeStepSpinner.Limits);
                
                if strcmpi(app.PointsforcirclefittingDropDown.Value,'Auto')
                    % Draw for Free wall
                    refPointFW  = ComputeFreeWallRefPoints(app,100); % set as 80[deg]. Need validation
                    for t = 1 : size(app.CoordContours,1)
                        app.TimeStepSpinner.Value = t;
                        % for free wall
                        points{1}(1,:) = refPointFW(t,1,:);
                        points{2}(1,:) = refPointFW(t,2,:);
                        app.CurvatureFreeWall(t,1) = ComputeCurvature(app,points,1,2);
                    end

                    % Draw initial curvature plot using the reference points (Septal)
                    GetSeptalCurvature_allPhases(app);
                    app.TimeStepSpinner.Value = 1;
                else % manual
                    if_exists = contains({filelist_mpts.name},listPts(ip).name);
                    if any(if_exists)
                        % Load manualPoints flies and compute curvature
                        loadManualpoints_computeCurvature(app,fullfile(filelist_mpts(if_exists).folder,filelist_mpts(if_exists).name));
                        %updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
                    else 
                        warning('manualPoint does not exist for this case. Skipping...');
                    end
                end
                exportCurvatureToXlsx(app,listPts(ip).name,outXlDirName,outXlFileName,ip);
                waitbar(ip/length(listPts),h,['Processed ',num2str(ip,'%0d'),' of ',num2str(length(listPts)),' subjects...']);
            end
            close(h);
            h = msgbox('Finished!');            
        end

        % Value changed function: PointsforcirclefittingDropDown
        function PointsforcirclefittingDropDownValueChanged(app, event)
            value = app.PointsforcirclefittingDropDown.Value;

            if app.initialCall == 0 && strcmp(value,'Manual')
                app.messagetxt.FontColor = 'w';
                app.messagetxt.Value = 'Manual mode activated. Place 3 points at the septal wall at the initial time step.';
                if ~app.registrationDone
                    app.TimeStepSpinner.Value = 1;
                    currentTimeStep = app.TimeStepSpinner.Value;

                    set(app.TimeStepSpinner,'Enable','off');
                    % delete contours and show only images
                    hold(app.ContourView, 'off');
                    hdl = imshow(app.SAXimg{currentTimeStep},[0 512],'Parent',app.ContourView, ...
                        'Xdata',[1 size(app.SAXimg{currentTimeStep},2)*app.PixelSpacing(1)], ...
                        'Ydata',[1 size(app.SAXimg{currentTimeStep},1)*app.PixelSpacing(2)]);
                    set(hdl,'HitTest','off');
                    hold(app.ContourView, 'on');
                    % reset plot panes
                    reset_plotviews(app,app.CurvatureView);
                    reset_plotviews(app,app.CurvatureRatioView);
                    reset_plotviews(app,app.CurvatureAreaView);
                else
                    currentTimeStep = app.TimeStepSpinner.Value;
                    drawManualCurves(app,currentTimeStep);
                    updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
                    reset_plotviews(app,app.CurvatureAreaView);  
                    app.messagetxt.Value = 'Please correct curves at all phases by dragging control points.';
                end
            elseif app.initialCall == 0 && strcmp(value,'Auto')
                % go back to auto mode
                set(app.TimeStepSpinner,'Enable','on');
                currentTimeStep = app.TimeStepSpinner.Value;
                app.messagetxt.FontColor = 'w';
                app.messagetxt.Value = 'Auto mode.';
                drawContours(app,currentTimeStep);
                updateCurvaturePlot(app,app.CurvatureSeptum(:,1),app.CurvatureFreeWall(:,1));
                app.CountPointsSeptal   = 0;
                app.CountPointsFreewall = 0;
            end
        end

        % Button down function: ContourView
        function ContourViewButtonDown(app, event)
            currentTimeStep = app.TimeStepSpinner.Value;
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                if ~app.registrationDone
                    if app.CountPointsSeptal < 3
                        hold(app.ContourView,'on');
                        app.CountPointsSeptal = app.CountPointsSeptal + 1;
                        app.Points{2,currentTimeStep}(app.CountPointsSeptal,:) = app.ContourView.CurrentPoint(1,1:2);
                        plot(app.ContourView,app.ContourView.CurrentPoint(1,1),app.ContourView.CurrentPoint(1,2),'ro');
                        if app.CountPointsSeptal == 3
                            drawSpline_tx_ty(app,app.Points{2,currentTimeStep}(:,1),app.Points{2,currentTimeStep}(:,2),100,'r');
                            app.messagetxt.Value = 'Place 3 points at the LV free wall';
                        end
                    elseif app.CountPointsSeptal == 3 && app.CountPointsFreewall < 3
                        app.messagetxt.FontColor = 'y';
                        app.CountPointsFreewall = app.CountPointsFreewall + 1;
                        app.Points{1,currentTimeStep}(app.CountPointsFreewall,:) = app.ContourView.CurrentPoint(1,1:2);
                        plot(app.ContourView,app.ContourView.CurrentPoint(1,1),app.ContourView.CurrentPoint(1,2),'go');
                        pause(0.1);
                        if app.CountPointsFreewall == 3
                            drawSpline_tx_ty(app,app.Points{1,currentTimeStep}(:,1),app.Points{1,currentTimeStep}(:,2),100,'g');
                            app.messagetxt.Value = ['Doing registration in phase (1/',num2str(app.TimeStepSpinner.Limits(2),'%0d'),')...'];
                            % registration
                            registration_points(app);
                            app.CountPointsSeptal   = 0;
                            app.CountPointsFreewall = 0;
                            app.messagetxt.Value = 'Please correct curves at all phases by dragging control points.';                    
                        end
                    end
                else % manual correction
                    xdata = [app.Points{1,currentTimeStep}(:,1); app.Points{2,currentTimeStep}(:,1)];
                    ydata = [app.Points{1,currentTimeStep}(:,2); app.Points{2,currentTimeStep}(:,2)];
                    if 5 > min(sqrt((xdata-app.ContourView.CurrentPoint(1,1)).^2+(ydata-app.ContourView.CurrentPoint(1,2)).^2),[],1)
                        [~,app.pointToMove] = min(sqrt((xdata-app.ContourView.CurrentPoint(1,1)).^2+(ydata-app.ContourView.CurrentPoint(1,2)).^2),[],1);
                        set(app.SeptalCurvatureToolUIFigure,...
                            'WindowButtonMotionFcn',  {@DragMouse_Callback,'move'},...
                            'WindowButtonUpFcn',      {@DragMouse_Callback,'up'});
                    end

                end
            end
            
            function DragMouse_Callback(hobj,~,action)
                pos = get(app.ContourView,'CurrentPoint');
                switch action
                    case 'move'
                        if app.pointToMove<=3
                            app.Points{1,currentTimeStep}(app.pointToMove  ,1) = pos(1);
                            app.Points{1,currentTimeStep}(app.pointToMove  ,2) = pos(3);
                        else
                            app.Points{2,currentTimeStep}(app.pointToMove-3,1) = pos(1);
                            app.Points{2,currentTimeStep}(app.pointToMove-3,2) = pos(3);
                        end
                        drawManualCurves(app,currentTimeStep);
                    case 'up'
                        set(app.SeptalCurvatureToolUIFigure,...
                            'WindowButtonMotionFcn',  '',...
                            'WindowButtonUpFcn',      '');
                        
                        [  ~,  ~, rinv ] = CircleFitting(app,app.Points{1,currentTimeStep}(:,1),app.Points{1,currentTimeStep}(:,2));
                        app.CurvatureFreeWall(currentTimeStep,2) = rinv;
                        [ cx, cy, rinv ] = CircleFitting(app,app.Points{2,currentTimeStep}(:,1),app.Points{2,currentTimeStep}(:,2));
                        sept_to_freewall = mean(app.Points{1,currentTimeStep},1)-mean(app.Points{2,currentTimeStep},1);
                        sept_to_center   = [cx cy] - mean(app.Points{2,currentTimeStep},1);
                        if dot(sept_to_center,sept_to_freewall) > 0
                            app.CurvatureSeptum(currentTimeStep,2)   = rinv;
                        else
                            app.CurvatureSeptum(currentTimeStep,2)   = -rinv;
                        end
                        updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
                end
            end


        end

        % Button pushed function: ResetmanualpointsButton
        function ResetmanualpointsButtonPushed(app, event)
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                app.TimeStepSpinner.Value = 1;
                currentTimeStep = app.TimeStepSpinner.Value;
                app.registrationDone = false;
                hold(app.ContourView, 'off');
                hdl = imshow(app.SAXimg{currentTimeStep},[0 512],'Parent',app.ContourView, ...
                    'Xdata',[1 size(app.SAXimg{currentTimeStep},2)*app.PixelSpacing(1)], ...
                    'Ydata',[1 size(app.SAXimg{currentTimeStep},1)*app.PixelSpacing(2)]);
                set(hdl,'HitTest','off');
                hold(app.ContourView, 'on');
                % reset plot panes
                reset_plotviews(app,app.CurvatureView);
                reset_plotviews(app,app.CurvatureRatioView);
                reset_plotviews(app,app.CurvatureAreaView);
                app.Points = [];
                app.messagetxt.Value = 'Place 3 points at the LV free wall';
                set(app.TimeStepSpinner,'Enable','off');
            end
        end

        % Button pushed function: SavemanualpointsButton
        function SavemanualpointsButtonPushed(app, event)
            saveManualpoints(app);
        end

        % Button pushed function: LoadmanualpointsButton
        function LoadmanualpointsButtonPushed(app, event)
            loadManualpoints_computeCurvature(app);
        end

        % Close request function: SeptalCurvatureToolUIFigure
        function SeptalCurvatureToolUIFigureCloseRequest(app, event)
            if_close = uiconfirm(app.SeptalCurvatureToolUIFigure, 'Close the app?','CurvatureTool');
            if strcmpi(if_close,'OK')
                delete(app);
                rmpath('./func');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SeptalCurvatureToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {738, 738, 738};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {738, 738};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 379};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SeptalCurvatureToolUIFigure = uifigure('Visible', 'off');
            app.SeptalCurvatureToolUIFigure.Name = 'Septal Curvature Tool - (c)  University of Colorado Anschutz Medical Campus';
            app.SeptalCurvatureToolUIFigure.Icon = fullfile(pathToMLAPP, 'uiicon.png');
            app.SeptalCurvatureToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @SeptalCurvatureToolUIFigureCloseRequest, true);
            app.SeptalCurvatureToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SeptalCurvatureToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadContourButton = uibutton(app.LeftPanel, 'push');
            app.LoadContourButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContourButtonPushed, true);
            app.LoadContourButton.Text = 'Load Contour';
            app.TimeStepSpinnerLabel = uilabel(app.LeftPanel);
            app.TimeStepSpinnerLabel.Text = 'Time Step';
            app.TimeStepSpinner = uispinner(app.LeftPanel);
            app.TimeStepSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeStepSpinnerValueChanged, true);
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ReferencepointsDropDownLabel = uilabel(app.LeftPanel);
            app.ReferencepointsDropDownLabel.Text = 'Reference points';
            app.ReferencepointsDropDown = uidropdown(app.LeftPanel);
            app.ReferencepointsDropDown.Items = {'From file', 'Automatic'};
            app.ReferencepointsDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferencepointsDropDownValueChanged, true);
            app.ReferencepointsDropDown.Value = 'From file';
            app.CurvatureareaDropDownLabel = uilabel(app.LeftPanel);
            app.CurvatureareaDropDownLabel.Text = 'Curvature area ';
            app.CurvatureareaDropDown = uidropdown(app.LeftPanel);
            app.CurvatureareaDropDown.Items = {'Line-based', 'Curve-based'};
            app.CurvatureareaDropDown.ValueChangedFcn = createCallbackFcn(app, @CurvatureareaDropDownValueChanged, true);
            app.CurvatureareaDropDown.Value = 'Line-based';
            app.BatchanalysisButton = uibutton(app.LeftPanel, 'push');
            app.BatchanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BatchanalysisButtonPushed, true);
            app.BatchanalysisButton.Text = 'Batch analysis';
            app.PointsforcirclefittingLabel = uilabel(app.LeftPanel);
            app.PointsforcirclefittingLabel.Text = {'Points for '; 'circle fitting'};
            app.PointsforcirclefittingDropDown = uidropdown(app.LeftPanel);
            app.PointsforcirclefittingDropDown.Items = {'Auto', 'Manual'};
            app.PointsforcirclefittingDropDown.ValueChangedFcn = createCallbackFcn(app, @PointsforcirclefittingDropDownValueChanged, true);
            app.PointsforcirclefittingDropDown.Value = 'Auto';
            app.SavemanualpointsButton = uibutton(app.LeftPanel, 'push');
            app.SavemanualpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SavemanualpointsButtonPushed, true);
            app.SavemanualpointsButton.Text = 'Save manual points';
            app.LoadmanualpointsButton = uibutton(app.LeftPanel, 'push');
            app.LoadmanualpointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmanualpointsButtonPushed, true);
            app.LoadmanualpointsButton.Text = 'Load manual points';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ContourView = uiaxes(app.CenterPanel);
            title(app.ContourView, 'Contour View')
            xlabel(app.ContourView, 'X [mm]')
            ylabel(app.ContourView, 'Y [mm]')
            app.ContourView.ButtonDownFcn = createCallbackFcn(app, @ContourViewButtonDown, true);
            app.messagetxt = uitextarea(app.CenterPanel);
            app.ResetmanualpointsButton = uibutton(app.CenterPanel, 'push');
            app.ResetmanualpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetmanualpointsButtonPushed, true);
            app.ResetmanualpointsButton.Text = 'Reset manual points ';
            app.RightPanel = uipanel(app.GridLayout);
            app.CurvatureView = uiaxes(app.RightPanel);
            title(app.CurvatureView, 'Curvature')
            xlabel(app.CurvatureView, 'Time Step')
            ylabel(app.CurvatureView, 'Curvature [1/mm]')
            app.CurvatureRatioView = uiaxes(app.RightPanel);
            title(app.CurvatureRatioView, 'Normalized Curvature [Septal/FreeWall]')
            xlabel(app.CurvatureRatioView, 'Time Step')
            ylabel(app.CurvatureRatioView, 'Normalized Curvature [ - ]')
            zlabel(app.CurvatureRatioView, 'Z')
            app.CurvatureAreaView = uiaxes(app.RightPanel);
            title(app.CurvatureAreaView, 'Curvature area')
            xlabel(app.CurvatureAreaView, 'Time Step')
            ylabel(app.CurvatureAreaView, 'Curvature area [mm^2]')
            zlabel(app.CurvatureAreaView, 'Z')
            % Show the figure after all components are created
            app.SeptalCurvatureToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curvatureTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeptalCurvatureToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeptalCurvatureToolUIFigure)
        end
    end
end